classdef robotGUI < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TabGroup                    matlab.ui.container.TabGroup
        MotorDimensionsTab          matlab.ui.container.Tab
        UseDefaultValuesButton      matlab.ui.control.Button
        axleLength                  matlab.ui.control.NumericEditField
        OuterstatorradiusmmLabel_5  matlab.ui.control.Label
        ShowMotorPreviewButton      matlab.ui.control.Button
        rotorLength                 matlab.ui.control.NumericEditField
        OuterstatorradiusmmLabel_4  matlab.ui.control.Label
        statorLength                matlab.ui.control.NumericEditField
        OuterstatorradiusmmLabel_3  matlab.ui.control.Label
        innerStatorDiameter         matlab.ui.control.NumericEditField
        OuterstatorradiusmmLabel_2  matlab.ui.control.Label
        outerStatorDiameter         matlab.ui.control.NumericEditField
        OuterstatorradiusmmLabel    matlab.ui.control.Label
        innerRotorDiameter          matlab.ui.control.NumericEditField
        InnerrotorradiusmmLabel     matlab.ui.control.Label
        outerRotorDiameter          matlab.ui.control.NumericEditField
        OuterrotorradiusmmLabel     matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        Tab2                        matlab.ui.container.Tab
    end

    properties (Access = public)
        MotionAnlysisClass
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShowMotorPreviewButton
        function ShowMotorPreviewButtonPushed(comp, event)
            MPA = MotionPowerAnalysis;

            % Define Input Values
            OuterStatorDiameter = comp.outerStatorDiameter.Value; % mm
            InnerStatorDiameter = comp.innerStatorDiameter.Value; % mm
            OuterRotorDiameter = comp.outerRotorDiameter.Value; % mm
            InnerRotorDiameter = comp.innerRotorDiameter.Value; % mm
            AxleLength = comp.axleLength.Value; % mm
            StatorLength = comp.statorLength.Value; % mm
            RotorLength = comp.rotorLength.Value; % mm

            % Create a new figure for the plot
            figPosition = [8,9,555,491];
            newFig = figure('Position', figPosition, 'Name', 'Motor Visualization', 'NumberTitle', 'off');
            ax = axes(newFig);
            hold(ax, 'on');

            % Plot the rotor as a solid cylinder
            MPA.plotSolidCylinder(ax, OuterRotorDiameter, InnerRotorDiameter, RotorLength, 'red', 0);

            % Plot the stator as a solid cylinder inside the rotor
            MPA.plotSolidCylinder(ax, OuterStatorDiameter, InnerStatorDiameter, StatorLength, 'blue', 0);

            % Plot the axle as a solid cylinder
            MPA.plotSolidCylinder(ax, InnerStatorDiameter, 0, AxleLength, 'green', 0);

            % Set axis properties
            axis(ax, 'equal');
            xlabel(ax, 'X [mm]');
            ylabel(ax, 'Y [mm]');
            zlabel(ax, 'Z [mm]');
            title(ax, 'Motor ');
            grid(ax, 'on');
            view(ax, 45, 45);
            hold(ax, 'off');


        end

        % Button pushed function: UseDefaultValuesButton
        function UseDefaultValuesButtonPushed(comp, event)
            % Define Default Input Values
            comp.outerStatorDiameter.Value = 29; % mm
            comp.innerStatorDiameter.Value = 6; % mm
            comp.outerRotorDiameter.Value = 35; % mm
            comp.innerRotorDiameter.Value = 30; % mm
            comp.axleLength.Value = 54; % mm
            comp.statorLength.Value = 36; % mm
            comp.rotorLength.Value = 36; % mm
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)


        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 1308 672];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.TabGroup = uitabgroup(comp);
            comp.MotorDimensionsTab = uitab(comp.TabGroup);
            comp.MotorDimensionsTab.Title = 'Motor Dimensions';
            comp.Image = uiimage(comp.MotorDimensionsTab);
            comp.Image.ImageSource = fullfile(pathToMLAPP, 'motorImage.png');
            comp.OuterrotorradiusmmLabel = uilabel(comp.MotorDimensionsTab);
            comp.OuterrotorradiusmmLabel.Text = 'Outer rotor diameter (mm)';
            comp.outerRotorDiameter = uieditfield(comp.MotorDimensionsTab, 'numeric');
            comp.InnerrotorradiusmmLabel = uilabel(comp.MotorDimensionsTab);
            comp.InnerrotorradiusmmLabel.Text = 'Inner rotor diameter (mm)';
            comp.innerRotorDiameter = uieditfield(comp.MotorDimensionsTab, 'numeric');
            comp.OuterstatorradiusmmLabel = uilabel(comp.MotorDimensionsTab);
            comp.OuterstatorradiusmmLabel.Text = 'Outer stator diameter (mm)';
            comp.outerStatorDiameter = uieditfield(comp.MotorDimensionsTab, 'numeric');
            comp.OuterstatorradiusmmLabel_2 = uilabel(comp.MotorDimensionsTab);
            comp.OuterstatorradiusmmLabel_2.Text = 'Axle diameter (mm)';
            comp.innerStatorDiameter = uieditfield(comp.MotorDimensionsTab, 'numeric');
            comp.OuterstatorradiusmmLabel_3 = uilabel(comp.MotorDimensionsTab);
            comp.OuterstatorradiusmmLabel_3.Text = 'Stator Length (mm)';
            comp.statorLength = uieditfield(comp.MotorDimensionsTab, 'numeric');
            comp.OuterstatorradiusmmLabel_4 = uilabel(comp.MotorDimensionsTab);
            comp.OuterstatorradiusmmLabel_4.Text = 'Rotor Length (mm)';
            comp.rotorLength = uieditfield(comp.MotorDimensionsTab, 'numeric');
            comp.ShowMotorPreviewButton = uibutton(comp.MotorDimensionsTab, 'push');
            comp.ShowMotorPreviewButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ShowMotorPreviewButtonPushed, true);
            comp.ShowMotorPreviewButton.Text = 'Show Motor Preview';
            comp.OuterstatorradiusmmLabel_5 = uilabel(comp.MotorDimensionsTab);
            comp.OuterstatorradiusmmLabel_5.Text = 'Axle Length (mm)';
            comp.axleLength = uieditfield(comp.MotorDimensionsTab, 'numeric');
            comp.UseDefaultValuesButton = uibutton(comp.MotorDimensionsTab, 'push');
            comp.UseDefaultValuesButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @UseDefaultValuesButtonPushed, true);
            comp.UseDefaultValuesButton.Text = 'Use Default Values';
            comp.Tab2 = uitab(comp.TabGroup);
            comp.Tab2.Title = 'Tab2';
        end
    end
end